classdef mainApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        AuditorystimulationsystemPanel  matlab.ui.container.Panel
        DataPathPanel                   matlab.ui.container.Panel
        PathSelectButton                matlab.ui.control.Button
        DataPathEditField               matlab.ui.control.EditField
        ControlPanel                    matlab.ui.container.Panel
        MonitorButton                   matlab.ui.control.Button
        StartButton                     matlab.ui.control.Button
        AddSubjectButton                matlab.ui.control.Button
        SetParamsButton                 matlab.ui.control.Button
        NextButton                      matlab.ui.control.Button
        StopButton                      matlab.ui.control.Button
        StatePanel                      matlab.ui.container.Panel
        StateLamp                       matlab.ui.control.Lamp
        StateLabel                      matlab.ui.control.Label
        PhaseSelectPanel                matlab.ui.container.Panel
        RulesPathEditField              matlab.ui.control.EditField
        RulesSelectButton               matlab.ui.control.Button
        PhaseSelectTree                 matlab.ui.container.CheckBoxTree
    end


    properties (Access = public)
        subjectInfo
        params
        dataPath
        pIDList
        protocolList
        protocol
        pIDIndex
        status
        mTimer
        activeID
        rulesPath
        nRepeat
        cueLag
        pIDsRules
        codes
        behavApp
        intervalMax
    end
    
    methods (Access = public)
        
        function timerInit(app)
            app.mTimer = timer;
            app.mTimer.Period = 1;
            app.mTimer.ExecutionMode = 'fixedSpacing';
            app.mTimer.TimerFcn = @(~, ~) timerHandler(app);
        end

        function timerHandler(app)
            currentTime = app.intervalMax * 60 - app.mTimer.TasksExecuted;
            
            if currentTime == 0
                NextButtonPushed(app, []);
            else
                app.NextButton.Text = ['下一阶段(', num2str(currentTime), 's)'];
                drawnow;
            end

        end

    end
    
    methods (Access = private)
        
        function createNodes(app)
            app.PhaseSelectTree.Children.delete;

            tb = readtable(app.rulesPath);
            app.protocol = unique(tb.protocol, 'stable');
            app.protocolList.Text = cellfun(@(x, y) strcat(x, '-', y), tb.node0Hint, tb.nodeHint, "UniformOutput", false);
            app.protocolList.Text = unique(app.protocolList.Text, 'stable');
            app.protocolList.pID = unique(tb.pID, 'stable');
            app.params.ITIs = tb.ITI;
            app.activeID = unique(tb.pID(strcmp(tb.apType, 'active')));
            app.nRepeat = tb.nRepeat;
            app.cueLag = tb.cueLag;
            app.pIDsRules = tb.pID;
            app.codes = tb.code;

            % Create node0
            node0Hint = unique(tb.node0Hint, 'stable');
            for index = 1:length(node0Hint)
                node0(index) = uitreenode(app.PhaseSelectTree);
                node0(index).NodeData = 10000 + index;
                node0(index).Text = node0Hint{index};
            end

            % Create node
            n = 0;
            for index0 = 1:length(node0)
                idx = strcmp(tb.node0Hint, node0Hint{index0});
                pID = unique(tb.pID(idx), 'stable');
                nodeHint = unique(tb.nodeHint(idx), 'stable');
                for index = 1:length(pID)
                    n = n + 1;
                    node(n) = uitreenode(node0(index0));
                    node(n).NodeData = pID(index);
                    node(n).Text = nodeHint{index};
                end
            end

            expand(app.PhaseSelectTree);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            cd(fileparts(mfilename("fullpath")));
            addpath(genpath(pwd));

            app.UIFigure.Name = '听觉任务刺激系统';
            srceenSize = get(0, "screensize");
            figSize = app.UIFigure.Position;
            figSize(1) = (srceenSize(3) - figSize(3)) / 2;
            figSize(2) = (srceenSize(4) - figSize(4)) / 2;
            app.UIFigure.Position = figSize;

            app.intervalMax = 10; % min

            try
                load('config.mat', 'config');
                app.params = config.params;
                app.dataPath = config.dataPath;
                app.subjectInfo = config.subjectInfo;
                app.DataPathEditField.Value = config.dataPath;
                app.rulesPath = config.rulesPath;
            catch
                app.params.cueLag = 0;
                app.params.nRepeat = 40;
                app.params.fs = 384; % kHz
                app.params.volumn = 0.3;
                app.params.choiceWin = 2; % sec
                app.params.itiJitter = 0; % sec
                app.params.triggerType = 'None';
                app.params.address = hex2dec('378');
                app.params.COM = 1;
                app.params.useSettingnRepeat = false;
                app.rulesPath = fullfile(fileparts(mfilename("fullpath")), 'rules\rules.xlsx');
            end

            app.status = 'stop';
            app.StateLamp.Color = [0.8, 0.8, 0.8];

            app.RulesPathEditField.Value = app.rulesPath;
            createNodes(app);
        end

        % Button pushed function: AddSubjectButton
        function AddSubjectButtonPushed(app, event)
            subjectInfoInputApp(app, app.subjectInfo);
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            try
                addpath(fileparts(mfilename("fullpath")), '-begin');
                InitializePsychSound;
                PsychPortAudio('Close');
                
                if isempty(app.dataPath)
                    app.dataPath = pwd;
                elseif ~contains(app.dataPath, '\') % relative path
                    app.dataPath = fullfile(pwd, app.dataPath);
                end
    
                config.params = app.params;
                config.dataPath = app.dataPath;
                config.rulesPath = app.rulesPath;
                config.subjectInfo = app.subjectInfo;
                config.time = datestr(now, 'yyyy-mm-dd HH:MM:ss');
                save(fullfile(fileparts(mfilename("fullpath")), 'config.mat'), 'config');
    
                try
                    delete(app.params.ioObj);
                end

                app.params.ioObj = InitTrigger(app.params.triggerType, app.params.COM);

                if isempty([app.PhaseSelectTree.CheckedNodes])
                    error("请选择试验阶段");
                end

                app.pIDList = [app.PhaseSelectTree.CheckedNodes.NodeData];
                app.pIDList(app.pIDList >= 10000) = [];
                app.pIDIndex = 1;
                app.StateLabel.Text = app.protocolList.Text{app.protocolList.pID == app.pIDList(app.pIDIndex)};
                
                temp = fullfile(app.dataPath, [datestr(now, 'yyyymmdd'), '-', app.subjectInfo.ID]);
                mkdir(temp);
                save(fullfile(temp, 'config.mat'), 'config');
                app.DataPathEditField.Value = app.dataPath;
    
                app.AddSubjectButton.Enable = 'off';
                app.SetParamsButton.Enable = 'off';
                app.StartButton.Enable = 'off';
                app.StopButton.Enable = 'on';
                app.PhaseSelectPanel.Enable = 'off';
                app.DataPathPanel.Enable = 'off';
    
                app.status = 'start';
                app.StateLamp.Color = [0, 1, 0];
    
                drawnow;

                if ismember(app.pIDList(app.pIDIndex), app.activeID)
                    activeFcn(app);
                else
                    passiveFcn(app);
                end
                
            catch ME
                Msgbox(ME.message, 'Error', 'top-center');
            end
        end

        % Button pushed function: SetParamsButton
        function SetParamsButtonPushed(app, event)
            paramsSettingApp(app, app.params);
        end

        % Button pushed function: PathSelectButton
        function PathSelectButtonPushed(app, event)
            temp = uigetdir;

            if temp ~= 0
                app.dataPath = temp;
                app.DataPathEditField.Value = temp;
            end
        end

        % Value changed function: DataPathEditField
        function DataPathEditFieldValueChanged(app, event)
            app.dataPath = app.DataPathEditField.Value;
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.AddSubjectButton.Enable = 'on';
            app.SetParamsButton.Enable = 'on';
            app.StartButton.Enable = 'on';
            app.NextButton.Enable = 'off';
            app.StopButton.Enable = 'off';
            app.PhaseSelectPanel.Enable = 'on';
            app.DataPathPanel.Enable = 'on';

            app.StateLabel.Text = ['终止于: ', app.protocolList.Text{app.protocolList.pID == app.pIDList(app.pIDIndex)}];

            try
                stop(app.mTimer);
                delete(app.mTimer);
            end

            app.NextButton.Text = '下一阶段';
            app.status = 'stop';
            app.StateLamp.Color = [0.8, 0.8, 0.8];
            drawnow;

            try
                playAudio(fullfile(fileparts(mfilename("fullpath")), "sounds\hint\terminate.mp3"), app.params.fs * 1000);
            end
            
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            app.NextButton.Enable = 'off';
            app.NextButton.Text = '下一阶段';
            drawnow;
            
            try
                stop(app.mTimer);
                delete(app.mTimer);
            end

            app.pIDIndex = app.pIDIndex + 1;

            if app.pIDIndex <= length(app.pIDList)
                app.StateLabel.Text = app.protocolList.Text{app.protocolList.pID == app.pIDList(app.pIDIndex)};
                drawnow;
                
                if ismember(app.pIDList(app.pIDIndex), app.activeID)
                    activeFcn(app);
                else
                    passiveFcn(app);
                end

            else
                StopButtonPushed(app, event);
            end
            
        end

        % Value changed function: RulesPathEditField
        function RulesPathEditFieldValueChanged(app, event)
            app.rulesPath = app.RulesPathEditField.Value;

            try
                createNodes(app);
            catch ME
                Msgbox(ME.message, 'Error', 'top-center');
            end
        end

        % Button pushed function: RulesSelectButton
        function RulesSelectButtonPushed(app, event)
            [temp, path] = uigetfile(fullfile(fileparts(mfilename("fullpath")), 'rules\*.xlsx'), 'Pick a rules file');

            if temp ~= 0
                app.rulesPath = fullfile(path, temp);
                app.RulesPathEditField.Value = fullfile(path, temp);
                createNodes(app);
            end
        end

        % Button pushed function: MonitorButton
        function MonitorButtonPushed(app, event)
            app.behavApp = behaviorPlotApp;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AuditorystimulationsystemPanel = uipanel(app.UIFigure);
            app.AuditorystimulationsystemPanel.Title = '听觉任务刺激系统(Auditory stimulation system)';
            app.PhaseSelectPanel = uipanel(app.AuditorystimulationsystemPanel);
            app.PhaseSelectPanel.Title = '试验阶段选择(Rule setting)';
            app.PhaseSelectTree = uitree(app.PhaseSelectPanel, 'checkbox');
            app.RulesSelectButton = uibutton(app.PhaseSelectPanel, 'push');
            app.RulesSelectButton.ButtonPushedFcn = createCallbackFcn(app, @RulesSelectButtonPushed, true);
            app.RulesSelectButton.Tag = 'pathSelectButton';
            app.RulesSelectButton.Text = '···';
            app.RulesPathEditField = uieditfield(app.PhaseSelectPanel, 'text');
            app.RulesPathEditField.ValueChangedFcn = createCallbackFcn(app, @RulesPathEditFieldValueChanged, true);
            app.RulesPathEditField.Tag = 'dataPath';
            app.RulesPathEditField.Placeholder = 'rules路径(Rule path)';
            app.StatePanel = uipanel(app.AuditorystimulationsystemPanel);
            app.StatePanel.Title = '当前阶段(Status)';
            app.StateLabel = uilabel(app.StatePanel);
            app.StateLabel.Tag = 'stateLabel';
            app.StateLabel.Text = '未开始';
            app.StateLamp = uilamp(app.StatePanel);
            app.ControlPanel = uipanel(app.AuditorystimulationsystemPanel);
            app.ControlPanel.Title = '控制面板(Control panel)';
            app.StopButton = uibutton(app.ControlPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Tag = 'stopButton';
            app.StopButton.Enable = 'off';
            app.StopButton.Text = '试验终止(Terminate)';
            app.NextButton = uibutton(app.ControlPanel, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Tag = 'nextButton';
            app.NextButton.Enable = 'off';
            app.NextButton.Text = '下一阶段(Next session)';
            app.SetParamsButton = uibutton(app.ControlPanel, 'push');
            app.SetParamsButton.ButtonPushedFcn = createCallbackFcn(app, @SetParamsButtonPushed, true);
            app.SetParamsButton.Tag = 'setParamsButton';
            app.SetParamsButton.Text = '参数设置(Parameter setting)';
            app.AddSubjectButton = uibutton(app.ControlPanel, 'push');
            app.AddSubjectButton.ButtonPushedFcn = createCallbackFcn(app, @AddSubjectButtonPushed, true);
            app.AddSubjectButton.Tag = 'addSubjectButton';
            app.AddSubjectButton.Text = '编辑被试信息(Subject info)';
            app.StartButton = uibutton(app.ControlPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Tag = 'startButton';
            app.StartButton.Enable = 'off';
            app.StartButton.Text = '开始(Start)';
            app.MonitorButton = uibutton(app.ControlPanel, 'push');
            app.MonitorButton.ButtonPushedFcn = createCallbackFcn(app, @MonitorButtonPushed, true);
            app.MonitorButton.Text = '实时行为查看(Monitor)';
            app.DataPathPanel = uipanel(app.AuditorystimulationsystemPanel);
            app.DataPathPanel.Title = '数据存储根目录(Data storage path)';
            app.DataPathEditField = uieditfield(app.DataPathPanel, 'text');
            app.DataPathEditField.ValueChangedFcn = createCallbackFcn(app, @DataPathEditFieldValueChanged, true);
            app.DataPathEditField.Tag = 'dataPath';
            app.DataPathEditField.Placeholder = '请输入数据存储根目录（将以日期-被试编号格式创建文件夹）';
            app.PathSelectButton = uibutton(app.DataPathPanel, 'push');
            app.PathSelectButton.ButtonPushedFcn = createCallbackFcn(app, @PathSelectButtonPushed, true);
            app.PathSelectButton.Tag = 'pathSelectButton';
            app.PathSelectButton.Text = '···';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mainApp

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end