classdef paramsSettingApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        CancelButton            matlab.ui.control.Button
        ConfirmButton           matlab.ui.control.Button
        SubjectInfoPanel        matlab.ui.container.Panel
        secLabel_4              matlab.ui.control.Label
        StartIntervalEditField  matlab.ui.control.NumericEditField
        Label_14                matlab.ui.control.Label
        UserstartSwitch         matlab.ui.control.Switch
        UserstartSwitchLabel    matlab.ui.control.Label
        secLabel_2              matlab.ui.control.Label
        secLabel_3              matlab.ui.control.Label
        secLabel                matlab.ui.control.Label
        kHzLabel                matlab.ui.control.Label
        forceCheckBox           matlab.ui.control.CheckBox
        comEditField            matlab.ui.control.NumericEditField
        Label_13                matlab.ui.control.Label
        cueLagEditField         matlab.ui.control.NumericEditField
        sLabel                  matlab.ui.control.Label
        addressEditField        matlab.ui.control.NumericEditField
        triggerTypeDropDown     matlab.ui.control.DropDown
        Label_12                matlab.ui.control.Label
        itiJitterEditField      matlab.ui.control.NumericEditField
        minLabel                matlab.ui.control.Label
        Label_11                matlab.ui.control.Label
        choiceWinEditField      matlab.ui.control.NumericEditField
        Label_10                matlab.ui.control.Label
        volumnEditField         matlab.ui.control.NumericEditField
        VolumnLabel             matlab.ui.control.Label
        fsEditField             matlab.ui.control.NumericEditField
        Label_9                 matlab.ui.control.Label
        nRepeatEditField        matlab.ui.control.NumericEditField
        Label_8                 matlab.ui.control.Label
    end

    
    properties (Access = private)
        app_parent
        params
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, app_parent, params)
            app.UIFigure.Name = '听觉任务刺激系统';
            srceenSize = get(0, "screensize");
            figSize = app.UIFigure.Position;
            figSize(1) = (srceenSize(3) - figSize(3)) / 2;
            figSize(2) = (srceenSize(4) - figSize(4)) / 2;
            app.UIFigure.Position = figSize;

            app.app_parent = app_parent;
            
            if ~isempty(params)
                try
                    app.params = params;
                    app.cueLagEditField.Value = params.cueLag;
                    app.nRepeatEditField.Value = params.nRepeat;
                    app.fsEditField.Value = params.fs;
                    app.volumnEditField.Value = params.volumn;
                    app.choiceWinEditField.Value = params.choiceWin;
                    app.itiJitterEditField.Value = params.itiJitter;
                    app.triggerTypeDropDown.Value = params.triggerType;
                    app.addressEditField.Value = params.address;
                    app.comEditField.Value = params.COM;
                    app.forceCheckBox.Value = params.useSettingnRepeat;
                    app.UserstartSwitch.Value = params.userStart;
                    app.StartIntervalEditField.Value = params.startInterval;
                catch ME
                    msgbox(ME.message);
                end
            end

            if strcmpi(params.triggerType, 'LTP')
                app.addressEditField.Enable = 'on';
            else
                app.addressEditField.Enable = 'off';
            end

            if strcmpi(params.triggerType, 'COM')
                app.comEditField.Enable = 'on';
            else
                app.comEditField.Enable = 'off';
            end

        end

        % Button pushed function: ConfirmButton
        function ConfirmButtonPushed(app, event)
            app.params.cueLag = app.cueLagEditField.Value;
            app.params.nRepeat = app.nRepeatEditField.Value;
            app.params.fs = app.fsEditField.Value;
            app.params.volumn = app.volumnEditField.Value;
            app.params.choiceWin = app.choiceWinEditField.Value;
            app.params.itiJitter = app.itiJitterEditField.Value;
            app.params.triggerType = app.triggerTypeDropDown.Value;
            app.params.address = app.addressEditField.Value;
            app.params.COM = app.comEditField.Value;
            app.params.useSettingnRepeat = app.forceCheckBox.Value;
            app.params.userStart = app.UserstartSwitch.Value;
            app.params.startInterval = app.StartIntervalEditField.Value;
            app.app_parent.params = app.params;
            delete(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app);
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app);
        end

        % Value changed function: triggerTypeDropDown
        function triggerTypeDropDownValueChanged(app, event)
            value = app.triggerTypeDropDown.Value;
            if strcmpi(value, 'LTP')
                app.addressEditField.Enable = 'on';
            else
                app.addressEditField.Enable = 'off';
            end
            if strcmpi(value, 'COM')
                app.comEditField.Enable = 'on';
            else
                app.comEditField.Enable = 'off';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.SubjectInfoPanel = uipanel(app.UIFigure);
            app.SubjectInfoPanel.Title = '参数设置(Parameter setting)';
            app.Label_8 = uilabel(app.SubjectInfoPanel);
            app.Label_8.Text = '重复次数(Repeats)';
            app.nRepeatEditField = uieditfield(app.SubjectInfoPanel, 'numeric');
            app.nRepeatEditField.Limits = [0 Inf];
            app.nRepeatEditField.Value = 40;
            app.Label_9 = uilabel(app.SubjectInfoPanel);
            app.Label_9.Text = '设备采样率(Sample rate)';
            app.fsEditField = uieditfield(app.SubjectInfoPanel, 'numeric');
            app.fsEditField.Limits = [0 Inf];
            app.fsEditField.Value = 384;
            app.VolumnLabel = uilabel(app.SubjectInfoPanel);
            app.VolumnLabel.Text = '音量设置(Volumn)';
            app.volumnEditField = uieditfield(app.SubjectInfoPanel, 'numeric');
            app.volumnEditField.Limits = [0 1];
            app.volumnEditField.Value = 0.3;
            app.Label_10 = uilabel(app.SubjectInfoPanel);
            app.Label_10.Text = '选择窗口(Choice window)';
            app.choiceWinEditField = uieditfield(app.SubjectInfoPanel, 'numeric');
            app.choiceWinEditField.Limits = [0 Inf];
            app.choiceWinEditField.Value = 2;
            app.Label_11 = uilabel(app.SubjectInfoPanel);
            app.Label_11.Text = 'LTP地址(十进制)';
            app.minLabel = uilabel(app.SubjectInfoPanel);
            app.minLabel.Text = '间隔随机(ITI jitter)';
            app.itiJitterEditField = uieditfield(app.SubjectInfoPanel, 'numeric');
            app.itiJitterEditField.Limits = [0 Inf];
            app.Label_12 = uilabel(app.SubjectInfoPanel);
            app.Label_12.Text = '触发方式(Trigger)';
            app.triggerTypeDropDown = uidropdown(app.SubjectInfoPanel);
            app.triggerTypeDropDown.Items = {'None', 'LTP', 'COM', 'TriggerBox'};
            app.triggerTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @triggerTypeDropDownValueChanged, true);
            app.triggerTypeDropDown.Value = 'None';
            app.addressEditField = uieditfield(app.SubjectInfoPanel, 'numeric');
            app.addressEditField.Limits = [0 Inf];
            app.addressEditField.Value = 888;
            app.sLabel = uilabel(app.SubjectInfoPanel);
            app.sLabel.Text = '按键提示音间隔(Cue lag)';
            app.cueLagEditField = uieditfield(app.SubjectInfoPanel, 'numeric');
            app.cueLagEditField.Limits = [0 Inf];
            app.Label_13 = uilabel(app.SubjectInfoPanel);
            app.Label_13.Text = 'COM串口序号';
            app.comEditField = uieditfield(app.SubjectInfoPanel, 'numeric');
            app.comEditField.Limits = [0 Inf];
            app.comEditField.ValueDisplayFormat = '%.0f';
            app.comEditField.Value = 1;
            app.forceCheckBox = uicheckbox(app.SubjectInfoPanel);
            app.forceCheckBox.Text = '强制';
            app.kHzLabel = uilabel(app.SubjectInfoPanel);
            app.kHzLabel.Text = 'kHz';
            app.secLabel = uilabel(app.SubjectInfoPanel);
            app.secLabel.Text = 'sec';
            app.secLabel_3 = uilabel(app.SubjectInfoPanel);
            app.secLabel_3.Text = 'sec';
            app.secLabel_2 = uilabel(app.SubjectInfoPanel);
            app.secLabel_2.Text = 'sec';
            app.UserstartSwitchLabel = uilabel(app.SubjectInfoPanel);
            app.UserstartSwitchLabel.Text = '用户开启(User-start)';
            app.UserstartSwitch = uiswitch(app.SubjectInfoPanel, 'slider');
            app.Label_14 = uilabel(app.SubjectInfoPanel);
            app.Label_14.Text = '开启间隔(Start interval)';
            app.StartIntervalEditField = uieditfield(app.SubjectInfoPanel, 'numeric');
            app.StartIntervalEditField.Limits = [0 Inf];
            app.StartIntervalEditField.Value = 0.6;
            app.secLabel_4 = uilabel(app.SubjectInfoPanel);
            app.secLabel_4.Text = 'sec';
            app.ConfirmButton = uibutton(app.UIFigure, 'push');
            app.ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButtonPushed, true);
            app.ConfirmButton.Tag = 'confirmButton';
            app.ConfirmButton.Text = '确认';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = '取消';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = paramsSettingApp(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end