classdef subjectInfoInputApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        GridLayout2         matlab.ui.container.GridLayout
        CancelButton        matlab.ui.control.Button
        OKButton            matlab.ui.control.Button
        SubjectInfoPanel    matlab.ui.container.Panel
        GridLayout          matlab.ui.container.GridLayout
        CommentEditField    matlab.ui.control.TextArea
        Label_8             matlab.ui.control.Label
        DiagnosisEditField  matlab.ui.control.TextArea
        Label_7             matlab.ui.control.Label
        IDEditField         matlab.ui.control.EditField
        IDLabel             matlab.ui.control.Label
        ContactEditField    matlab.ui.control.EditField
        Label_6             matlab.ui.control.Label
        ProEditField        matlab.ui.control.EditField
        Label_5             matlab.ui.control.Label
        NameEditField       matlab.ui.control.EditField
        Label_4             matlab.ui.control.Label
        BirthdayDatePicker  matlab.ui.control.DatePicker
        Label_2             matlab.ui.control.Label
        GenderDropDown      matlab.ui.control.DropDown
        Label               matlab.ui.control.Label
        NoteFieldsmarkedwitharerequiredLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        app_parent
        subjectInfo
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, app_parent, subjectInfo)
            app.UIFigure.Name = 'Stimulation system';
            app.app_parent = app_parent;
            app.UIFigure.WindowState = "normal";

            if isempty(subjectInfo)
                subjectInfo = struct();
            end
            app.subjectInfo = subjectInfo;
            
            app.IDEditField.Value        = mu.ifelse(isfield(subjectInfo, "ID"        ), @() subjectInfo.ID        , []);
            app.NameEditField.Value      = mu.ifelse(isfield(subjectInfo, "name"      ), @() subjectInfo.name      , []);
            app.GenderDropDown.Value     = mu.ifelse(isfield(subjectInfo, "gender"    ), @() subjectInfo.gender    , '--Please select--');
            app.BirthdayDatePicker.Value = mu.ifelse(isfield(subjectInfo, "birthDate" ), @() subjectInfo.birthDate , datetime("1990-01-01"));
            app.ProEditField.Value       = mu.ifelse(isfield(subjectInfo, "profession"), @() subjectInfo.profession, '');
            app.ContactEditField.Value   = mu.ifelse(isfield(subjectInfo, "contact"   ), @() subjectInfo.contact   , '');
            app.CommentEditField.Value   = mu.ifelse(isfield(subjectInfo, "comment"   ), @() subjectInfo.comment   , '');
            app.DiagnosisEditField.Value = mu.ifelse(isfield(subjectInfo, "diagnosis" ), @() subjectInfo.diagnosis , '');

            movegui(app.UIFigure, "center");
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            app.subjectInfo.ID         = app.IDEditField.Value;
            app.subjectInfo.name       = app.NameEditField.Value;
            app.subjectInfo.gender     = app.GenderDropDown.Value;
            app.subjectInfo.birthDate  = app.BirthdayDatePicker.Value;
            app.subjectInfo.profession = app.ProEditField.Value;
            app.subjectInfo.contact    = app.ContactEditField.Value;
            app.subjectInfo.comment    = app.CommentEditField.Value;
            app.subjectInfo.diagnosis  = app.DiagnosisEditField.Value;

            % Validate
            if isempty(app.subjectInfo.ID) || isempty(app.subjectInfo.name) || strcmp(app.subjectInfo.gender, '--Please select--')
                uialert(app.UIFigure, "Fields marked with * are required.", "Validation error");
            else
                app.app_parent.subjectInfo = app.subjectInfo;

                if ~isempty(app.subjectInfo)
                    app.app_parent.StartButton.Enable = 'on';
                end

                delete(app);
            end

        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout2 = uigridlayout(app.UIFigure);
            app.SubjectInfoPanel = uipanel(app.GridLayout2);
            app.SubjectInfoPanel.Title = 'Basic subject infomation';
            app.GridLayout = uigridlayout(app.SubjectInfoPanel);
            app.NoteFieldsmarkedwitharerequiredLabel = uilabel(app.GridLayout);
            app.NoteFieldsmarkedwitharerequiredLabel.Text = 'Note: Fields marked with * are required';
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = '*Gender';
            app.GenderDropDown = uidropdown(app.GridLayout);
            app.GenderDropDown.Items = {'--Please select--', 'Male', 'Female'};
            app.GenderDropDown.Tag = 'subjectGender';
            app.GenderDropDown.Value = '--Please select--';
            app.Label_2 = uilabel(app.GridLayout);
            app.Label_2.Text = '*Birthday';
            app.BirthdayDatePicker = uidatepicker(app.GridLayout);
            app.BirthdayDatePicker.Editable = 'off';
            app.BirthdayDatePicker.Tag = 'subjectBirthDate';
            app.BirthdayDatePicker.Value = datetime([1990 1 1]);
            app.Label_4 = uilabel(app.GridLayout);
            app.Label_4.Text = '*Name';
            app.NameEditField = uieditfield(app.GridLayout, 'text');
            app.NameEditField.Tag = 'subjectName';
            app.Label_5 = uilabel(app.GridLayout);
            app.Label_5.Text = 'Profession';
            app.ProEditField = uieditfield(app.GridLayout, 'text');
            app.ProEditField.Tag = 'subjectProfession';
            app.Label_6 = uilabel(app.GridLayout);
            app.Label_6.Text = 'Contact';
            app.ContactEditField = uieditfield(app.GridLayout, 'text');
            app.ContactEditField.Tag = 'subjectContact';
            app.ContactEditField.Placeholder = 'Phone number/email';
            app.IDLabel = uilabel(app.GridLayout);
            app.IDLabel.Text = '*ID';
            app.IDEditField = uieditfield(app.GridLayout, 'text');
            app.IDEditField.Tag = 'subjectID';
            app.Label_7 = uilabel(app.GridLayout);
            app.Label_7.Text = {'Diagnosis'; ''};
            app.DiagnosisEditField = uitextarea(app.GridLayout);
            app.DiagnosisEditField.Tag = 'subjectDiagnosis';
            app.DiagnosisEditField.Placeholder = 'Separate with comma';
            app.Label_8 = uilabel(app.GridLayout);
            app.Label_8.Text = 'Comment';
            app.CommentEditField = uitextarea(app.GridLayout);
            app.CommentEditField.Tag = 'comment';
            app.OKButton = uibutton(app.GridLayout2, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Tag = 'confirmButton';
            app.OKButton.Text = 'OK';
            app.CancelButton = uibutton(app.GridLayout2, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = subjectInfoInputApp(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end